classdef fly_on_the_ball_analysis_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        File                    matlab.ui.container.Menu
        Open                    matlab.ui.container.Menu
        CloseMenu               matlab.ui.container.Menu
        SaveVideoMenu           matlab.ui.container.Menu
        ViewMenu                matlab.ui.container.Menu
        TimestampsMenu          matlab.ui.container.Menu
        CamerasMenu             matlab.ui.container.Menu
        StimulusMenu            matlab.ui.container.Menu
        LabelsforDLCPanel       matlab.ui.container.Panel
        ShowlabelsCheckBox      matlab.ui.control.CheckBox
        ShowkeypointsCheckBox   matlab.ui.control.CheckBox
        Camera02CheckBox        matlab.ui.control.CheckBox
        Camera01CheckBox        matlab.ui.control.CheckBox
        ExportButton            matlab.ui.control.Button
        DisplayPanel            matlab.ui.container.Panel
        Flipcamera_02CheckBox   matlab.ui.control.CheckBox
        AdjustButton            matlab.ui.control.Button
        I3CheckBox              matlab.ui.control.CheckBox
        I2CheckBox              matlab.ui.control.CheckBox
        I1CheckBox              matlab.ui.control.CheckBox
        SingleRwdButton         matlab.ui.control.Button
        SingleFwdButton         matlab.ui.control.Button
        FramesSlider            matlab.ui.control.Slider
        FramesSliderLabel       matlab.ui.control.Label
        FwdButton               matlab.ui.control.Button
        RwdButton               matlab.ui.control.Button
        ReplayspeedSlider       matlab.ui.control.Slider
        ReplayspeedSliderLabel  matlab.ui.control.Label
        Panel                   matlab.ui.container.Panel
        TrialLabel              matlab.ui.control.Label
        CurrentFrameLabel       matlab.ui.control.Label
        FrameLabel              matlab.ui.control.Label
        TimeLabel               matlab.ui.control.Label
        CurrenttimeLabel        matlab.ui.control.Label
    end

    
    properties (Access = private)
        config 
        data_camera
        data_trial
        current_frame
        current_time
        last_frame
        reference_sampling_rate
        figure_handles
        dlc_training
        stimulus
    end
    
    methods (Access = private)
        
        function process_leg_tracking(app)            
            for iCamera = 1:numel(app.config.data_camera.names_cameras)
                name_camera = app.config.data_camera.names_cameras{iCamera};
                if app.data_camera.(['is_',name_camera])
                    data_temp = h5read( ...
                        [app.config.dir.data, ...
                         app.config.experiment, ...
                         app.config.data_camera.folder_tracking, ...
                         app.config.trial_name,'_',name_camera,'.h5'], ...
                         '/df_with_missing/table');
                    fields = fieldnames(data_temp);
                    app.data_camera.(['data_',name_camera]) = data_temp.(fields{end})'; 
                    app.data_camera.(['data_',name_camera]) (:,[46:48,61:end]) = nan;  
                    clearvars data_temp
                end
                clearvars name_camera
            end
            clearvars iCamera     
        end

        function plot_camera(app)
            close all % Change in future
            for iCamera = 1:numel(app.config.data_camera.names_cameras)
                name_camera = app.config.data_camera.names_cameras{iCamera};
                if app.data_camera.(['is_',name_camera]) 
                    vid_data = read(app.data_camera.(['vid_obj_',name_camera]),app.current_frame);
                    if app.Flipcamera_02CheckBox.Value && iCamera == 2
                            vid_data = fliplr(vid_data);
                    end
                    app.figure_handles.(name_camera) = figure;
                    imshow(vid_data,'border','tight');
                    set(app.figure_handles.(name_camera), ...
                        'menubar','none', ...
                        'NumberTitle','off', ...
                        'Name', name_camera, ...
                        'Position', app.config.display.(['pos_',name_camera]));
                end
            end
            if app.ShowkeypointsCheckBox.Value
                plot_keypoints(app)
            end
        end

        function plot_keypoints(app)
            if (app.Camera01CheckBox.Value ...
                    || app.Camera02CheckBox.Value) 
                if app.Camera01CheckBox.Value
                    iCamera = 1; 
                else
                    iCamera = 2;
                end
                
                name_camera = app.config.data_camera.names_cameras{iCamera};
                if isvalid(app.figure_handles.(name_camera))
                    labels = app.config.dlc_training.labels;
                    n_label = 0;
                    figure(app.figure_handles.(name_camera))
                    hold on
                    for iLabel = 1:numel(labels)
                        app.dlc_training.(labels{iLabel}) = images.roi.Point(...
                            gca, ...
                            'Position',[10,10+n_label*10], ...
                            'Label',labels{iLabel}, ...
                            'LabelVisible','hover', ... 
                            'Color', app.config.display.(['color_',labels{iLabel}]));
                        n_label = n_label+1;
                    end
                    hold off
                end
                if app.ShowlabelsCheckBox.Value
                    plot_keypoint_labels(app)
                end
            end
        end

        function plot_keypoint_labels(app)
            if app.Camera01CheckBox.Value
                iCamera = 1;
            else
                iCamera = 2;
            end
            name_camera = app.config.data_camera.names_cameras{iCamera};
            labels = app.figure_handles.(name_camera).Children.Children;
            labels = labels(1:end-1);
            if app.ShowlabelsCheckBox.Value
                label_visible = 'on';
            else
                label_visible = 'hover';
            end
            for iLabel = 1:numel(labels)
                labels(iLabel).LabelVisible = label_visible;
            end
        end

        function plot_timestamps(app)
            app.figure_handles.timestamps = figure;
            hold on
            plot(app.data_trial.Time, app.data_trial.([app.config.daq.name,'_ai0']))
            plot([app.current_time, app.current_time], [-5,5], 'k')
            hold off
            ylabel('Voltage (V)')
            set(gca,'color','none')
            ylim([-.2,4])
            set(app.figure_handles.timestamps, ...
                        'menubar','none', ...
                        'toolbar','figure',...
                        'NumberTitle','off', ...
                        'Name', 'Timestamps', ...
                        'Position', app.config.display.('pos_timestamps'));
        end

        function plot_stimulus(app)
            app.figure_handles.stimulus = figure;
            hold on
            plot(app.data_trial.Time, app.stimulus)
            plot([app.current_time, app.current_time], [-11,11], 'k')
            hold off
            ylabel('Voltage (V)')
            set(gca,'color','none')
            ylim([-10.5,10.5])
            set(app.figure_handles.stimulus, ...
                        'menubar','none', ...
                        'toolbar','figure',...
                        'NumberTitle','off', ...
                        'Name', 'Optogenetic stimulus', ...
                        'Position', app.config.display.('pos_stimulus'));
            disp(app.config.display.('pos_stimulus'))
        end

        function update_time(app)
            app.current_time = app.data_trial.Time(round(app.current_frame * ...
                app.config.daq.sampling_rate/app.reference_sampling_rate));
            app.TimeLabel.Text = string(app.current_time);
        end

        function update_figures(app)
            % Update video frames by updating CData
            for iCamera = 1:numel(app.config.data_camera.names_cameras)
                name_camera = app.config.data_camera.names_cameras{iCamera};
                if isfield(app.figure_handles,name_camera) ...
                        && isvalid(app.figure_handles.(name_camera))
                    % Load image
                    vid_data = read(app.data_camera.(['vid_obj_',name_camera]),app.current_frame);
                    if app.Flipcamera_02CheckBox.Value && iCamera == 2
                        vid_data = fliplr(vid_data);
                    end
                    app.figure_handles.(name_camera).CurrentAxes.Children(end).CData = vid_data;
                    drawnow
                
                    labels = app.figure_handles.(name_camera).Children.Children;
                end
            end

            % Update daq signal
            if isfield(app.figure_handles,'timestamps')
                if isvalid(app.figure_handles.timestamps)
                    app.figure_handles.timestamps.Children.Children(1).XData = ...
                        [app.current_time, app.current_time];
                end
            end

            % Update stimulus signal
            if isfield(app.figure_handles,'stimulus')
                if isvalid(app.figure_handles.stimulus)
                    app.figure_handles.stimulus.Children.Children(1).XData = ...
                        [app.current_time, app.current_time];
                end
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.FrameLabel.Text = "";
            app.TimeLabel.Text = "";
            app.TrialLabel.Text = "";
            app.I1CheckBox.Value = false;
            app.I2CheckBox.Value = false;
            app.I3CheckBox.Value = false;
        end

        % Menu selected function: Open
        function OpenMenuSelected(app, event)
            % Load configuration file
            path_config = 'config.toml';
            config = toml.read(path_config);
            app.config = toml.map_to_struct(config);

            % Set directories 
            app.config.dir.data = app.config.dir.data_default;

            [app.config.file_name,~] = uigetfile(...
                {'*.*'},...
                'Select mat file',...
                app.config.dir.data);
            if app.config.file_name == 0
                return
            end

            app.config.trial_name = app.config.file_name(1:end-4); 
            app.config.experiment = app.config.file_name(1:end-8);

            load([app.config.dir.data, ...
                app.config.experiment,'/', ...
                app.config.file_name]);
            app.data_trial = trial_data;

            app.stimulus = trial_data.([app.config.daq.name,'_',app.config.daq.channel_stimulus]);

            % Check if videos are available
            for iCamera = 1:numel(app.config.data_camera.names_cameras)
                name_camera = app.config.data_camera.names_cameras{iCamera};
                app.data_camera.(['is_',name_camera]) = false;
                if exist( ...
                        [app.config.dir.data, ...
                        app.config.experiment, ...
                        app.config.data_camera.folder_videos, ...
                        app.config.trial_name,'_', ...
                        app.config.data_camera.names_videos{iCamera},'.mp4'], ...
                        'file') == 2
            
                    app.data_camera.(['is_',name_camera]) = true;
                end
                clearvars name_camera
            end

            % Check if leg tracking data are available 
            app.data_camera.is_data = false;
            if exist( ...
                    [app.config.dir.data, ...
                     app.config.experiment, ...
                     app.config.data_camera.folder_tracking, ...
                     app.config.trial_name,'_', ... 
                     app.config.data_camera.names_videos{1},'.h5'],...
                    'file') == 2
                
                app.data_camera.is_data = true;
            end

            % Initialize videos 
            for iCamera = 1:numel(app.config.data_camera.names_cameras)
                name_camera = app.config.data_camera.names_cameras{iCamera};
                if app.data_camera.(['is_',name_camera])
                    app.data_camera.(['vid_obj_',app.config.data_camera.names_cameras{iCamera}]) = VideoReader( ...
                        [app.config.dir.data, ...
                         app.config.experiment, ...
                         app.config.data_camera.folder_videos, ...
                         app.config.trial_name,'_', ...
                         app.config.data_camera.names_cameras{iCamera},'.mp4']);
                end
                clearvars name_camera
            end

            % Process leg tracking data
            %if app.data_camera.is_data
            %    process_leg_tracking(app)
            %end
            
            % Set tracking label checkboxes
            for iLabel = 1:numel(app.config.display.legs)
                app.([app.config.display.legs{iLabel},'CheckBox']).Value = true;
            end

            app.current_frame = 1;
            app.current_time = 0;
            app.reference_sampling_rate = round(app.data_camera.vid_obj_camera_01.FrameRate);
            % Use the camera frame rate as the reference sampling rate.
            % Because the cameras continue recording for a few frames after
            % the end of the session, calculate the last available frame
            % based on the duration of the session.
            app.last_frame = round(seconds(app.data_trial.Time(end)))*app.reference_sampling_rate;
            app.FramesSlider.Limits = [1, app.last_frame];
            app.FramesSlider.Value = 1;
            app.ReplayspeedSlider.Limits = [1, app.last_frame/app.reference_sampling_rate*20];
            app.TrialLabel.Text = app.config.trial_name;
            app.FrameLabel.Text = string(app.current_frame);
            app.TimeLabel.Text = string(app.current_time);
            
            plot_camera(app) 
        end

        % Menu selected function: CloseMenu
        function CloseMenuSelected(app, event)
            close all
        end

        % Menu selected function: SaveVideoMenu
        function SaveVideoSelected(app, event)
            save_dir = '';
            start_frame = 1;  
            end_frame = 1200; 
            sampling_rate = 30;
            replay_speed = 1;  
            iCamera = 1;

            interval_frame = app.reference_sampling_rate/(sampling_rate/replay_speed);
            save_frames = start_frame:interval_frame:end_frame;

            daq_frames = round(save_frames*app.config.daq.sampling_rate/ ...
                app.reference_sampling_rate);

            stimulus_frames = app.stimulus(daq_frames);
            time_frames = seconds(app.data_trial.Time(daq_frames));
            % Express time relative to stimulus onset
            time_frames = time_frames - time_frames(find(stimulus_frames>-8,1,'first'));
            
            f = figure;
            n_frame = 0;
            for iFrame = save_frames
                n_frame = n_frame + 1;
                figure(f), clf

                % Plot video
                name_camera = app.config.data_camera.names_cameras{iCamera};
                vid_data = read(app.data_camera.(['vid_obj_',name_camera]),iFrame);
                imshow(vid_data,'border','tight');
                set(f, ...
                    'menubar','none', ...
                    'NumberTitle','off', ...
                    'Name', name_camera, ...
                    'Position', app.config.display.(['pos_',name_camera]));

                % Add time
                time_str = num2str(time_frames(n_frame),'%3.2f');
                text(850,60,[time_str,' s'], ...
                    'FontName', 'Arial', 'FontSize', 16, ...
                    'Color', [1,1,1], ...
                    'HorizontalAlignment', 'right')

                % Overlay stimulus indicator
                if stimulus_frames(n_frame) > -8 
                    hold on
                    rectangle('Position',[100,40,40,40],'FaceColor','w')
                end
                
                F(n_frame) = getframe(gcf);
            end
            close(f)

            % Create a video writer object
            writer_obj = VideoWriter(save_dir,'MPEG-4');
            writer_obj.FrameRate = sampling_rate;
            open(writer_obj);
            % Write frames to video
            for iFrame = 1:length(F)
                % Convert image to frame
                frame = F(iFrame);
                writeVideo(writer_obj, frame);
            end
            clearvars F
            % Close the video writer object
            close(writer_obj);
            disp(['Video saved as ',save_dir])
        end

        % Menu selected function: CamerasMenu
        function CamerasMenuSelected(app, event)
            plot_camera(app)
        end

        % Menu selected function: StimulusMenu
        function StimulusMenuSelected(app, event)
            plot_stimulus(app)
        end

        % Menu selected function: TimestampsMenu
        function TimestampsMenuSelected(app, event)
            plot_timestamps(app)
        end

        % Button pushed function: FwdButton
        function FwdButtonPushed(app, event)
            if strcmp(app.FwdButton.Text,'Fwd')
                app.FwdButton.Text = 'Stop';
                app.RwdButton.Text = 'Rwd';
            else
                app.FwdButton.Text = 'Fwd';
            end

            % Loop through frames
            replay_frames = round(app.ReplayspeedSlider.Value); 
            while app.current_frame <= app.last_frame - replay_frames
                if strcmp(app.FwdButton.Text,'Fwd')
                    break;
                end
                app.current_frame = round(app.FramesSlider.Value) + replay_frames;
                app.FramesSlider.Value = app.current_frame;
                app.FrameLabel.Text = string(app.current_frame); 
                update_time(app)
                update_figures(app)  
            end
            if app.current_frame > app.last_frame - replay_frames
                app.current_frame = 1;
                app.FramesSlider.Value = app.current_frame;
                app.FrameLabel.Text = string(app.current_frame);
                update_time(app)
                update_figures(app)  
                app.FwdButton.Text = 'Fwd';
            end
        end

        % Button pushed function: RwdButton
        function RwdButtonPushed(app, event)
            if strcmp(app.RwdButton.Text,'Rwd')
                app.RwdButton.Text = 'Stop';
            else
                app.RwdButton.Text = 'Rwd';
            end

            % Loop through frames
            replay_frames = round(app.ReplayspeedSlider.Value); 
            while app.current_frame >= 1 + replay_frames
                if strcmp(app.RwdButton.Text,'Rwd')
                    break;
                end
                app.current_frame = round(app.FramesSlider.Value) - replay_frames;
                app.FramesSlider.Value = app.current_frame;
                app.FrameLabel.Text = string(app.current_frame);
                update_time(app)
                update_figures(app)  
            end
            if app.current_frame < 1 + replay_frames
                app.current_frame = 1;
                app.FramesSlider.Value = app.current_frame;
                app.FrameLabel.Text = string(app.current_frame);
                update_time(app)
                update_figures(app)  
                app.RwdButton.Text = 'Rwd';
            end
        end

        % Button pushed function: SingleFwdButton
        function SingleFwdButtonPushed(app, event)
            value = round(app.FramesSlider.Value);
            value = value+1;
            if value>app.last_frame
                value = app.last_frame;
            end
            app.current_frame = value;
            app.FramesSlider.Value = value;
            app.FrameLabel.Text = string(value);

            update_time(app)
            update_figures(app)
        end

        % Button pushed function: SingleRwdButton
        function SingleRwdButtonPushed(app, event)
            value = round(app.FramesSlider.Value);
            value = value-1;
            if value<1
                value = 1;
            end
            app.current_frame = value;
            app.FramesSlider.Value = value;
            app.FrameLabel.Text = string(value);
                      
            update_time(app)
            update_figures(app)
        end

        % Value changed function: FramesSlider
        function FramesSliderValueChanged(app, event)
            value = round(app.FramesSlider.Value);
            app.current_frame = value;
            app.FrameLabel.Text = string(value);
                
            update_time(app)
            update_figures(app)
        end

        % Value changed function: I1CheckBox
        function I1CheckBoxSelected(app, event)
            value = app.I1CheckBox.Value;
            if value && ~any(strcmp(app.config.display.legs,'I1')) 
                app.config.display.legs = [{'I1'},app.config.display.legs];
            elseif ~value && any(strcmp(app.config.display.legs,'I1')) 
                app.config.display.legs(strcmp(app.config.display.legs,'I1')) = [];
            end
            app.config.display.legs = sort(app.config.display.legs);
        end

        % Value changed function: I2CheckBox
        function I2CheckBoxSelected(app, event)
            value = app.I2CheckBox.Value;
            if value && ~any(strcmp(app.config.display.legs,'I2')) 
                app.config.display.legs = [{'I2'},app.config.display.legs];
            elseif ~value && any(strcmp(app.config.display.legs,'I2')) 
                app.config.display.legs(strcmp(app.config.display.legs,'I2')) = [];
            end
            app.config.display.legs = sort(app.config.display.legs);
        end

        % Value changed function: I3CheckBox
        function I3CheckBoxSelected(app, event)
            value = app.I3CheckBox.Value;
            if value && ~any(strcmp(app.config.display.legs,'I3')) 
                app.config.display.legs = [{'I3'},app.config.display.legs];
            elseif ~value && any(strcmp(app.config.display.legs,'I3')) 
                app.config.display.legs(strcmp(app.config.display.legs,'I3')) = [];
            end
            app.config.display.legs = sort(app.config.display.legs);
        end

        % Button pushed function: AdjustButton
        function ViewAdjust(app, event)
            plot_camera(app)
        end

        % Value changed function: Camera01CheckBox
        function Camera01CheckBoxSelected(app, event)
            value = app.Camera01CheckBox.Value;
            if value 
                app.Camera02CheckBox.Value = false;
            end
        end

        % Value changed function: Camera02CheckBox
        function Camera02CheckBoxSelected(app, event)
            value = app.Camera02CheckBox.Value;
            if value 
                app.Camera01CheckBox.Value = false;
            end
        end

        % Value changed function: ShowkeypointsCheckBox
        function ShowkeypointsCheckBoxChanged(app, event)
            if app.ShowkeypointsCheckBox.Value
                plot_keypoints(app)
            else
                plot_camera(app)
            end
        end

        % Value changed function: ShowlabelsCheckBox
        function ShowlabelsChanged(app, event)
            plot_keypoint_labels(app)
        end

        % Button pushed function: ExportButton
        function DLCExportButton(app, event)
            x_ignore = 50;
            if app.Camera01CheckBox.Value
                iCamera = 1; 
            else
                iCamera = 2;
            end
            name_camera = app.config.data_camera.names_cameras{iCamera};
            labels = app.figure_handles.(name_camera).Children.Children;
            labels = labels(1:end-1); % Exclude video image
            labels = flipud(labels); % Invert order
            png_name = ['img',sprintf('%05d',str2double(app.FrameLabel.Text)),'.png'];
            labeled_data = [];
            col = 1;
            for iLabel = 1:numel(labels)
                if labels(iLabel).Position(1) > x_ignore
                    labeled_data(col) = labels(iLabel).Position(1); % X coordinate
                    labeled_data(col+1) = labels(iLabel).Position(2); % Y coordinate
                else               
                    labeled_data(col) = missing;
                    labeled_data(col+1) = missing;
                end
                col = col+2;
            end
            % Save mat-file
            save([app.config.dir.training_data,app.config.trial_name,'/',app.config.trial_name,'_',name_camera,'_',png_name(1:end-4),'.mat'],'labeled_data')
            % Plot video frame without labels and save as png
            vid_data = read(app.data_camera.(['vid_obj_',name_camera]),app.current_frame);
            % Flip camera 02 for labeling
            if iCamera == 2 
                vid_data = fliplr(vid_data);
            end
            figure
            imshow(vid_data,'border','tight');
            set(gcf, ...
                'menubar','none', ...
                'NumberTitle','off', ...
                'Name', name_camera, ...
                'Position', [200,200,size(vid_data,2),size(vid_data,1)]);
            export_fig([app.config.dir.training_data,app.config.trial_name,'/',app.config.trial_name,'_',name_camera,'_',png_name],gcf) % saveas() changes figure size 
            close(gcf)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.File = uimenu(app.UIFigure);
            app.File.Text = 'File';
            app.Open = uimenu(app.File);
            app.Open.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.Open.Text = 'Open';
            app.CloseMenu = uimenu(app.File);
            app.CloseMenu.MenuSelectedFcn = createCallbackFcn(app, @CloseMenuSelected, true);
            app.CloseMenu.Text = 'Close';
            app.SaveVideoMenu = uimenu(app.File);
            app.SaveVideoMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveVideoSelected, true);
            app.SaveVideoMenu.Text = 'Save Video';
            app.ViewMenu = uimenu(app.UIFigure);
            app.ViewMenu.Text = 'View';
            app.TimestampsMenu = uimenu(app.ViewMenu);
            app.TimestampsMenu.MenuSelectedFcn = createCallbackFcn(app, @TimestampsMenuSelected, true);
            app.TimestampsMenu.Text = 'Timestamps';
            app.CamerasMenu = uimenu(app.ViewMenu);
            app.CamerasMenu.MenuSelectedFcn = createCallbackFcn(app, @CamerasMenuSelected, true);
            app.CamerasMenu.Text = 'Cameras';
            app.StimulusMenu = uimenu(app.ViewMenu);
            app.StimulusMenu.MenuSelectedFcn = createCallbackFcn(app, @StimulusMenuSelected, true);
            app.StimulusMenu.Text = 'Stimulus';
            app.Panel = uipanel(app.UIFigure);
            app.CurrenttimeLabel = uilabel(app.Panel);
            app.CurrenttimeLabel.Text = 'Current time';
            app.TimeLabel = uilabel(app.Panel);
            app.TimeLabel.Text = 'Time';
            app.FrameLabel = uilabel(app.Panel);
            app.FrameLabel.Text = 'Frame';
            app.CurrentFrameLabel = uilabel(app.Panel);
            app.CurrentFrameLabel.Text = 'CurrentFrame';
            app.TrialLabel = uilabel(app.Panel);
            app.TrialLabel.Text = 'Trial';
            app.ReplayspeedSliderLabel = uilabel(app.UIFigure);
            app.ReplayspeedSliderLabel.Text = 'Replay speed';
            app.ReplayspeedSlider = uislider(app.UIFigure);
            app.ReplayspeedSlider.Limits = [1 300];
            app.ReplayspeedSlider.MajorTicks = [];
            app.ReplayspeedSlider.MajorTickLabels = {''};
            app.ReplayspeedSlider.MinorTicks = [];
            app.ReplayspeedSlider.Value = 1;
            app.RwdButton = uibutton(app.UIFigure, 'push');
            app.RwdButton.ButtonPushedFcn = createCallbackFcn(app, @RwdButtonPushed, true);
            app.RwdButton.Text = 'Rwd';
            app.FwdButton = uibutton(app.UIFigure, 'push');
            app.FwdButton.ButtonPushedFcn = createCallbackFcn(app, @FwdButtonPushed, true);
            app.FwdButton.Text = 'Fwd';
            app.FramesSliderLabel = uilabel(app.UIFigure);
            app.FramesSliderLabel.Text = 'Frames';
            app.FramesSlider = uislider(app.UIFigure);
            app.FramesSlider.MajorTicks = [];
            app.FramesSlider.ValueChangedFcn = createCallbackFcn(app, @FramesSliderValueChanged, true);
            app.FramesSlider.MinorTicks = [];
            app.SingleFwdButton = uibutton(app.UIFigure, 'push');
            app.SingleFwdButton.ButtonPushedFcn = createCallbackFcn(app, @SingleFwdButtonPushed, true);
            app.SingleFwdButton.Text = '>';
            app.SingleRwdButton = uibutton(app.UIFigure, 'push');
            app.SingleRwdButton.ButtonPushedFcn = createCallbackFcn(app, @SingleRwdButtonPushed, true);
            app.SingleRwdButton.Text = '<';
            app.DisplayPanel = uipanel(app.UIFigure);
            app.DisplayPanel.Title = 'Display';
            app.I1CheckBox = uicheckbox(app.DisplayPanel);
            app.I1CheckBox.ValueChangedFcn = createCallbackFcn(app, @I1CheckBoxSelected, true);
            app.I1CheckBox.Text = 'I1';
            app.I2CheckBox = uicheckbox(app.DisplayPanel);
            app.I2CheckBox.ValueChangedFcn = createCallbackFcn(app, @I2CheckBoxSelected, true);
            app.I2CheckBox.Text = 'I2';
            app.I3CheckBox = uicheckbox(app.DisplayPanel);
            app.I3CheckBox.ValueChangedFcn = createCallbackFcn(app, @I3CheckBoxSelected, true);
            app.I3CheckBox.Text = 'I3';
            app.AdjustButton = uibutton(app.DisplayPanel, 'push');
            app.AdjustButton.ButtonPushedFcn = createCallbackFcn(app, @ViewAdjust, true);
            app.AdjustButton.Text = 'Adjust';
            app.Flipcamera_02CheckBox = uicheckbox(app.DisplayPanel);
            app.Flipcamera_02CheckBox.Text = 'Flip camera_02';
            app.LabelsforDLCPanel = uipanel(app.UIFigure);
            app.LabelsforDLCPanel.Title = 'Labels for DLC';
            app.ExportButton = uibutton(app.LabelsforDLCPanel, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @DLCExportButton, true);
            app.ExportButton.Text = 'Export';
            app.Camera01CheckBox = uicheckbox(app.LabelsforDLCPanel);
            app.Camera01CheckBox.ValueChangedFcn = createCallbackFcn(app, @Camera01CheckBoxSelected, true);
            app.Camera01CheckBox.Text = 'Camera 01';
            app.Camera01CheckBox.Value = true;
            app.Camera02CheckBox = uicheckbox(app.LabelsforDLCPanel);
            app.Camera02CheckBox.ValueChangedFcn = createCallbackFcn(app, @Camera02CheckBoxSelected, true);
            app.Camera02CheckBox.Text = 'Camera 02';
            app.ShowkeypointsCheckBox = uicheckbox(app.LabelsforDLCPanel);
            app.ShowkeypointsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowkeypointsCheckBoxChanged, true);
            app.ShowkeypointsCheckBox.Text = 'Show keypoints';
            app.ShowlabelsCheckBox = uicheckbox(app.LabelsforDLCPanel);
            app.ShowlabelsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowlabelsChanged, true);
            app.ShowlabelsCheckBox.Text = 'Show labels';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fly_on_the_ball_analysis_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end